classdef GUIforFEA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DisplayResultDropDown           matlab.ui.control.DropDown
        DisplayResultDropDown_2Label    matlab.ui.control.Label
        SelectBoundaryConditionsButton  matlab.ui.control.Button
        MeshSlider                      matlab.ui.control.Slider
        MeshSliderLabel                 matlab.ui.control.Label
        GeometryDropDown                matlab.ui.control.DropDown
        GeometryDropDownLabel           matlab.ui.control.Label
        DrawGeometryButton              matlab.ui.control.Button
        PostProcessButton               matlab.ui.control.Button
        DisplayDeformedStructureButton  matlab.ui.control.Button
        SolveButton                     matlab.ui.control.Button
        DisplayGivenPlateButton         matlab.ui.control.Button
        PreProcessButton                matlab.ui.control.Button
        GetDataButton                   matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description

        n_element
        n_nodes
        E
        A
        ncon
        X
        Y
        NDU
        dzero
        F
        v
        t
        KE
        K
        KM
        U
        Sx
        Sy
        Sxy

        geometryMode   % 'excel' or 'manual'
        selectedNodes  % for BC selection
        meshDensity    % scalar
        resultType     % 'Sx','Sy','Sxy','Ex','Ey','Gxy'

        manualX        % manually created geometry
        manualY
        manualElements

    end
    
    methods (Access = private)
        
        function GenerateMesh(app)

            if strcmp(app.GeometryDropDown.Value,'Manual')

                if isempty(app.manualX)
                    error('No manual geometry defined');
                end

                tri = delaunay(app.manualX, app.manualY);

                app.ncon = tri;
                app.X = app.manualX;
                app.Y = app.manualY;

                app.n_nodes = length(app.X);
                app.n_element = size(tri,1);

            end
        end
        
            function startupFcn(app)

                app.geometryMode = 'excel';
                app.meshDensity = 1;
                app.selectedNodes = [];

                app.GeometryDropDown.Items = {'Excel','Manual'};
                app.GeometryDropDown.Value = 'Excel';

                app.DisplayResultDropDown.Items = {'Sx','Sy','Sxy'};
                app.DisplayResultDropDown.Value = 'Sx';

            end
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetDataButton
        function GetDataButtonPushed(app, event)
            
            %Import geometry and mesh data
            
            clc
            array = readmatrix('data_structure.xlsx', 'NumHeaderLines',1);
            data = array;

            app.n_element = data(1,1);
            app.n_nodes = data(1,2);
            app.E = data(1,8);
            app.A = data(1,9);
            app.ncon = [data(:,3),data(:,4),data(:,5)];
            app.X = data(:,6);
            app.Y = data(:,7);
            app.NDU = data(1,11);
            app.dzero = data(:,12);
            app.F = data(:,10);
            app.v = data(1,13);
            app.t = data(1,14);
        end

        % Button pushed function: PreProcessButton
        function PreProcessButtonPushed(app, event)
            
            %Assemble the global stiffness matrix
            GenerateMesh(app);

            % Apply mesh density (refinement)
            if strcmp(app.GeometryDropDown.Value,'Manual')

                if isempty(app.manualX)
                    return;
                end

                % Reset to original geometry before refining
                app.X = app.manualX;
                app.Y = app.manualY;

                for k = 1:round(app.MeshSlider.Value*2)   % refine based on slider
                    tri = delaunay(app.X, app.Y);
                    newX = [];
                    newY = [];

                    for i = 1:size(tri,1)
                        nodes = tri(i,:);
                        xc = mean(app.X(nodes));
                        yc = mean(app.Y(nodes));
                        newX(end+1) = xc;
                        newY(end+1) = yc;
                    end

                    app.X = [app.X; newX'];
                    app.Y = [app.Y; newY'];
                end

                app.n_nodes = length(app.X);
                app.ncon = delaunay(app.X, app.Y);
                app.n_element = size(app.ncon,1);
            end
            
            n_element = app.n_element;
            n_nodes = app.n_nodes;
            E = app.E;
            A = app.A; %Dummy
            ncon = app.ncon;
            X = app.X;
            Y = app.Y;
            NDU = app.NDU;
            dzero = app.dzero ;
            F = app.F;
            v = app.v;
            t = app.t;
            

            % Initiate Matricies
            KE = zeros(6);
            K = zeros(2*app.n_nodes);

            % Main Routine
            for i=1:n_element
                n1 = ncon(i,1);
                n2 = ncon(i,2);
                n3 = ncon(i,3);

                x1 = X(n1);
                x2 = X(n2);
                x3 = X(n3);

                y1 = Y(n1);
                y2 = Y(n2);
                y3 = Y(n3);

                b1 = (y2 - y3);
                b2 = (y3 - y1);
                b3 = (y1 - y2);

                c1 = (x3 - x2);
                c2 = (x1 - x3);
                c3 = (x2 - x1);
                
                A = 0.5 * det([1 x1 y1; 1 x2 y2; 1 x3 y3]);
                    

                B = (1/(2*A))*[b1 0 b2 0 b3 0
                    0 c1 0 c2 0 c3
                    c1 b1 c2 b2 c3 b3];

                D = (E/(1-v^2))*[1 v 0
                    v 1 0
                    0 0 ((1-v)/2)];

                KE = t*A*(B.')*D*B;
                % Assembles Overall Stiffness Matrix
                n1 = ncon(i,1);
                n2 = ncon(i,2);
                n3 = ncon(i,3);

                ROC(1) = (2*n1)-1;
                ROC(2) = (2*n1);
                ROC(3) = (2*n2)-1;
                ROC(4) = (2*n2);
                ROC(5) = (2*n3)-1;
                ROC(6) = (2*n3);

                for IX = 1:6
                    MI = ROC(IX);
                    for JX = 1:6
                        MJ = ROC(JX);

                        K(MI,MJ) = K(MI,MJ) + KE(IX,JX);
                    end
                end
            end

            app.K = K;
        end

        % Button pushed function: DisplayGivenPlateButton
        function DisplayGivenPlateButtonPushed(app, event)
            
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');
            grid(app.UIAxes, 'on');

            n_element = app.n_element;
            n_nodes = app.n_nodes;
            E = app.E;
            A = app.A; %Dummy
            ncon = app.ncon;
            X = app.X;
            Y = app.Y;
            NDU = app.NDU;
            dzero = app.dzero ;
            F = app.F;
            v = app.v;
            t = app.t;
            
            
            for i = 1:n_element

                n1 = ncon(i,1);
                n2 = ncon(i,2);
                n3 = ncon(i,3);

                x1 = X(n1);
                x2 = X(n2);
                x3 = X(n3);
                x4 = x1;

                y1 = Y(n1);
                y2 = Y(n2);
                y3 = Y(n3);
                y4 = y1;

                xc = (x1 + x2 + x3)/3;
                yc = (y1 + y2 + y3)/3;

                text(app.UIAxes, xc, yc, ...
                    ['E' num2str(i)], ...
                    'FontSize', 10, ...
                    'Color', 'black', ...
                    'FontWeight', 'bold');
                
                %Display Structures onto graphs
                % Filled element (light color)
                patch(app.UIAxes, [x1 x2 x3], [y1 y2 y3], ...
                    [0.9 0.9 0.9], 'EdgeColor', 'k', 'LineWidth', 1.5);

                hold(app.UIAxes,'on');
       
            end
                for i = 1:n_nodes
                    text(app.UIAxes, X(i), Y(i), ...
                        ['  N' num2str(i)], ...
                        'FontSize', 10, ...
                        'Color', 'blue', ...
                        'FontWeight', 'bold');
                end


        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)

            n_element = app.n_element;
            n_nodes = app.n_nodes;
            E = app.E;
            A = app.A; %Dummy
            ncon = app.ncon;
            X = app.X;
            Y = app.Y;
            NDU = app.NDU;
            dzero = app.dzero ;
            F = app.F;
            v = app.v;
            t = app.t;
            K=app.K;

            KM = K;

            % ADD THESE - trim to correct sizes
            F = F(1:2*n_nodes);
         
            if ~isempty(app.selectedNodes)
                dzero = [];
                for i = 1:length(app.selectedNodes)
                    n = app.selectedNodes(i);
                    dzero = [dzero, 2*n-1, 2*n]; % fix both DOFs
                end
                dzero = dzero(:);
                NDU = length(dzero);
            else
                dzero = dzero(1:NDU);
            end

            for k = 1:NDU
                n = dzero(k);
                KM(n,:) = 0;
                KM(:,n) = 0;
                KM(n,n) = 1;
                F(n) = 0;
            end

            U = KM \ F;
            
            Sx = zeros(n_element,1);
            Sy = zeros(n_element,1);
            Sxy = zeros(n_element,1);


            for i = 1:n_element
                n1 = ncon(i,1);
                n2 = ncon(i,2);
                n3 = ncon(i,3);

                x1 = X(n1);
                x2 = X(n2);
                x3 = X(n3);

                y1 = Y(n1);
                y2 = Y(n2);
                y3 = Y(n3);

                b1 = (y2 - y3);
                b2 = (y3 - y1);
                b3 = (y1 - y2);

                c1 = (x3 - x2);
                c2 = (x1 - x3);
                c3 = (x2 - x1);

                A = 0.5 * det([1 x1 y1; 1 x2 y2; 1 x3 y3]);

                B = (1/(2*A))*[b1 0 b2 0 b3 0
                    0 c1 0 c2 0 c3
                    c1 b1 c2 b2 c3 b3];

                D = (E/(1-v^2))*[1 v 0
                    v 1 0
                    0 0 ((1-v)/2)];

                u1 = U(2*n1-1);
                v1 = U(2*n1);
                u2 = U(2*n2-1);
                v2 = U(2*n2);
                u3 = U(2*n3-1);
                v3 = U(2*n3);

                d = [u1;v1;u2;v2;u3;v3];
                e = B*d;
                Sigma = D*e;

                Sx(i) = Sigma(1);
                Sy(i) = Sigma(2);
                Sxy(i) = Sigma(3);

            end

            app.Sx = Sx;
            app.Sy = Sy;
            app.Sxy = Sxy;

            app.U = U;
            app.Sx = Sx;
            app.Sy = Sy;
            app.Sxy = Sxy;

            disp(app.Sx)

        end

        % Button pushed function: DisplayDeformedStructureButton
        function DisplayDeformedStructureButtonPushed(app, event)

            cla(app.UIAxes_2);
            hold(app.UIAxes_2, 'on');
            axis(app.UIAxes_2, 'equal');
            grid(app.UIAxes_2, 'on');


            n_element = app.n_element;
            n_nodes = app.n_nodes;
            E = app.E;
            A = app.A; %Dummy
            ncon = app.ncon;
            X = app.X;
            Y = app.Y;
            NDU = app.NDU;
            dzero = app.dzero ;
            F = app.F;
            v = app.v;
            t = app.t;
            K=app.K;
            U = app.U;
         
           scale = 0.1 * max(abs([X; Y]));

            for i = 1:n_element

                n1 = ncon(i,1);
                n2 = ncon(i,2);
                n3 = ncon(i,3);

                x1 = X(n1);
                x2 = X(n2);
                x3 = X(n3);
                x4 = x1;

                y1 = Y(n1);
                y2 = Y(n2);
                y3 = Y(n3);
                y4 = y1;

                u1 = U(2*n1-1);
                v1 = U(2*n1);
                u2 = U(2*n2-1);
                v2 = U(2*n2);
                u3 = U(2*n3-1);
                v3 = U(2*n3);

                scale = 0.2 * max(abs([X; Y])) / (max(abs(U)) + 1e-12);

                xf1 = X(n1) + scale*u1;
                xf2 = X(n2) + scale*u2;
                xf3 = X(n3) + scale*u3;
                xf4 = xf1;

                yf1 = Y(n1) + scale*v1;
                yf2 = Y(n2) + scale*v2;
                yf3 = Y(n3) + scale*v3;
                yf4 = yf1;
                
                plot(app.UIAxes_2,[xf1 xf2 xf3 xf4],[yf1 yf2 yf3 yf4],'LineWidth',5,'Color','red')
                hold (app.UIAxes_2,'on');  
            end

            for i = 1:n_nodes
                    xd = X(i) + scale*U(2*i-1);
                    yd = Y(i) + scale*U(2*i);

                    text(app.UIAxes_2, xd, yd, ...
                        ['N' num2str(i)], ...
                        'FontSize', 10, ...
                        'Color', 'blue');
                end


        end

        % Button pushed function: PostProcessButton
        function PostProcessButtonPushed(app, event)

                cla(app.UIAxes_2);
                hold(app.UIAxes_2,'on');
                axis(app.UIAxes_2,'equal');
                grid(app.UIAxes_2,'on');

                % Select field
                switch app.DisplayResultDropDown.Value
                    case 'Sx'
                        field = app.Sx;
                    case 'Sy'
                        field = app.Sy;
                    case 'Sxy'
                        field = app.Sxy;
                end

                % Compute nodal averaging
                nodeVal = zeros(app.n_nodes,1);
                count = zeros(app.n_nodes,1);

                for i = 1:app.n_element
                    nodes = app.ncon(i,:);
                    for j = 1:3
                        nodeVal(nodes(j)) = nodeVal(nodes(j)) + field(i);
                        count(nodes(j)) = count(nodes(j)) + 1;
                    end
                end

                nodeVal = nodeVal ./ max(count,1);

                % Plot contour
                for i = 1:app.n_element
                    nodes = app.ncon(i,:);
                    x = app.X(nodes);
                    y = app.Y(nodes);
                    c = nodeVal(nodes);

                    patch(app.UIAxes_2, ...
                        'XData', x, ...
                        'YData', y, ...
                        'FaceVertexCData', c, ...
                        'FaceColor','interp', ...
                        'EdgeColor','k');
                end

                colormap(app.UIAxes_2, parula);
                colorbar(app.UIAxes_2);

                caxis(app.UIAxes_2,[min(field) max(field)]);

                title(app.UIAxes_2, ...
                    ['Stress Contour (' app.DisplayResultDropDown.Value ')']);


        end

        % Button pushed function: DrawGeometryButton
        function DrawGeometryButtonPushed(app, event)
            cla(app.UIAxes);
            hold(app.UIAxes,'on');

            title(app.UIAxes,'Click nodes. Press Enter when done');

            [x,y] = ginput;

            app.manualX = x;
            app.manualY = y;

            % Generate mesh immediately
            tri = delaunay(x,y);

            app.ncon = tri;
            app.X = x;
            app.Y = y;
            app.n_nodes = length(x);
            app.n_element = size(tri,1);

            % Plot mesh
            triplot(tri,x,y,'k','Parent',app.UIAxes);
            plot(app.UIAxes,x,y,'ro');
            
            %triangulate with delaunay(x,y)
            %assign to app.ncon

        end

        % Button pushed function: SelectBoundaryConditionsButton
        function SelectBoundaryConditionsButtonPushed(app, event)
            title(app.UIAxes,'Click nodes to fix');

            [x_click,y_click] = ginput;

            selected = [];

            for k = 1:length(x_click)
                dist = sqrt((app.X - x_click(k)).^2 + (app.Y - y_click(k)).^2);
                [~, idx] = min(dist);
                selected(end+1) = idx;
            end

            app.selectedNodes = unique(selected);

            % Visual feedback
            scatter(app.UIAxes, app.X(app.selectedNodes), ...
                app.Y(app.selectedNodes), 80, 'filled','r');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Tool Geometry & Deformed Shape')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Contour Plot')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.GetDataButton = uibutton(app.UIFigure, 'push');
            app.GetDataButton.ButtonPushedFcn = createCallbackFcn(app, @GetDataButtonPushed, true);
            app.GetDataButton.Text = 'Get Data';
            app.PreProcessButton = uibutton(app.UIFigure, 'push');
            app.PreProcessButton.ButtonPushedFcn = createCallbackFcn(app, @PreProcessButtonPushed, true);
            app.PreProcessButton.Text = 'Pre Process';
            app.DisplayGivenPlateButton = uibutton(app.UIFigure, 'push');
            app.DisplayGivenPlateButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayGivenPlateButtonPushed, true);
            app.DisplayGivenPlateButton.Text = 'Display Given Plate';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.DisplayDeformedStructureButton = uibutton(app.UIFigure, 'push');
            app.DisplayDeformedStructureButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayDeformedStructureButtonPushed, true);
            app.DisplayDeformedStructureButton.Text = 'Display Deformed Structure';
            app.PostProcessButton = uibutton(app.UIFigure, 'push');
            app.PostProcessButton.ButtonPushedFcn = createCallbackFcn(app, @PostProcessButtonPushed, true);
            app.PostProcessButton.Text = 'Post Process';
            app.DrawGeometryButton = uibutton(app.UIFigure, 'push');
            app.DrawGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @DrawGeometryButtonPushed, true);
            app.DrawGeometryButton.Text = 'Draw Geometry';
            app.GeometryDropDownLabel = uilabel(app.UIFigure);
            app.GeometryDropDownLabel.Text = 'Geometry Drop Down';
            app.GeometryDropDown = uidropdown(app.UIFigure);
            app.MeshSliderLabel = uilabel(app.UIFigure);
            app.MeshSliderLabel.Text = 'Mesh Slider';
            app.MeshSlider = uislider(app.UIFigure);
            app.SelectBoundaryConditionsButton = uibutton(app.UIFigure, 'push');
            app.SelectBoundaryConditionsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBoundaryConditionsButtonPushed, true);
            app.SelectBoundaryConditionsButton.Text = 'Select Boundary Conditions';
            app.DisplayResultDropDown_2Label = uilabel(app.UIFigure);
            app.DisplayResultDropDown_2Label.Text = 'Display Result';
            app.DisplayResultDropDown = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIforFEA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end